classdef VECTO_summary_2_0 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        GridLayout                  matlab.ui.container.GridLayout
        Panel_1                     matlab.ui.container.Panel
        GridLayout_1                matlab.ui.container.GridLayout
        NosummaryfilesCheckBox      matlab.ui.control.CheckBox
        CreateDutycyclesLabel       matlab.ui.control.Label
        Button                      matlab.ui.control.Button
        SummaryfilesgeneratorLabel  matlab.ui.control.Label
        PathEditField               matlab.ui.control.EditField
        OpenFilesButton             matlab.ui.control.Button
        FilesListBox                matlab.ui.control.ListBox
        UITable                     matlab.ui.control.Table
        ExportButton                matlab.ui.control.Button
        Panel_2                     matlab.ui.container.Panel
        GridLayout_2                matlab.ui.container.GridLayout
        DutyCyclesGeneratorLabel    matlab.ui.control.Label
        PathEditField_2             matlab.ui.control.EditField
        OpenFilesButton_2           matlab.ui.control.Button
        FilesListBox_2              matlab.ui.control.ListBox
        ExportButton_2              matlab.ui.control.Button
        VarsfoundLabel              matlab.ui.control.Label
        VarsFoundListBox            matlab.ui.control.ListBox
        Button_2                    matlab.ui.control.Button
        workspacevarsLabel          matlab.ui.control.Label
        VarsFoundListBox_2          matlab.ui.control.ListBox
        XaxisDropDown               matlab.ui.control.DropDown
        YaxisDropDown               matlab.ui.control.DropDown
        GenerateButton              matlab.ui.control.Button
        XbinsEditField              matlab.ui.control.NumericEditField
        AutobinsCheckBox            matlab.ui.control.CheckBox
        YbinsEditField              matlab.ui.control.NumericEditField
        FDREditField                matlab.ui.control.EditField
        WheeloutputCheckBox         matlab.ui.control.CheckBox
        FDREditFieldLabel           matlab.ui.control.Label
        YbinsEditFieldLabel         matlab.ui.control.Label
        XbinsEditFieldLabel         matlab.ui.control.Label
        YaxisDropDownLabel          matlab.ui.control.Label
        XaxisDropDownLabel          matlab.ui.control.Label
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.PathEditField.Value =cd;
        end

        % Button pushed function: OpenFilesButton
        function OpenFilesButtonPushed(app, event)
            [selectedFiles, folderPath] = uigetfile('*.vsum', 'Seleccionar archivos *.vsum', 'MultiSelect', 'on');
            if isequal(selectedFiles, 0) % Ningún archivo seleccionado
                disp('No se seleccionaron archivos.');
            else
                disp(['Archivos seleccionados desde el directorio: ', folderPath]);
                % app.FilesTextArea.Value = selectedFiles;
                if ischar(selectedFiles)
                    app.FilesListBox.Items = {selectedFiles};
                else
                    app.FilesListBox.Items = selectedFiles;
                end 
                app.PathEditField.Value =folderPath;
                root = cd (folderPath);
            end
        end

        % Button pushed function: ExportButton
        function ExportButtonPushed(app, event)
             for i=1:length(app.FilesListBox.Value)
                 if length(app.FilesListBox.Value)>1
                    Summary_file=app.FilesListBox.Value{app.FilesListBox.ValueIndex(i)};
                 else   
                    Summary_file=app.FilesListBox.Value{1};
                 end
                fechaHoraFormateada = datestr(datetime("now"),'dd.mm.yyyy_HH.MM');
                Summary_filename=[erase(Summary_file,'.vsum'),'_Summary_',fechaHoraFormateada,'.xlsx'];
                readfile = readlines(Summary_file);
                Vecto_version = readfile{1};
                Hash = readfile{end-1};
                % Escribir los datos en un nuevo archivo CSV con ',' como delimitador
                writematrix(Vecto_version,Summary_filename,'Sheet','Summary','Range','A1',"AutoFitWidth",true,"PreserveFormat",true);
                writematrix(Hash,Summary_filename,'Sheet','Summary','Range','A2',"AutoFitWidth",true,"PreserveFormat",true);
                data_table = readtable(Summary_file,'VariableNamingRule', 'preserve','FileType','delimitedtext','delimiter',',');
                % Summary_data = table2cell(rows2vars(data_table(1:end-1,:)));
                Summary_data = table2array(rows2vars(data_table(1:end-1,:)));
                writecell(Summary_data,Summary_filename,'Sheet','Summary','Range','A3',"AutoFitWidth",true,"PreserveFormat",true);
            end
            app.UITable.Data = (rows2vars(data_table(1:end-1,:)));
            FDR = Summary_data{find(strcmp(Summary_data(:,1), 'Axle gear ratio [-]')),2};
            app.Button.Enable = 'on';
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            filename
            vmod_files={}; 
            vmod_files = uigetfile('*.vmod','Seleccionar archivo *.vmod','MultiSelect', 'on');
            % files = {dir('*.vmod').name};
            columnSelection = []; % Variable para almacenar la selección de columnas
            
        end

        % Clicked callback: FilesListBox
        function FilesListBoxClicked(app, event)
            app.Button.Enable = 'off';
            app.Panel_2.Enable = 'off';
            item = event.InteractionInformation.Item;
            filename = app.FilesListBox.Items{item};
            try
            fechaHoraFormateada = datestr(datetime("now"),'dd.mm.yyyy_HH.MM');
            Summary_filename=[erase(filename,'.vsum'),'_app.FilesTextAreaapp.FilesTextAreaSummary_',fechaHoraFormateada,'.xlsx'];
            readfile = readlines(filename);
            Vecto_version = readfile{1};
            Hash = readfile{end-1};
            data_table = readtable(filename,'VariableNamingRule', 'preserve','FileType','delimitedtext','delimiter',',');
            Summary_data = table2array(rows2vars(data_table(1:end-1,:)));
            app.UITable.Data = (rows2vars(data_table(1:end-1,:)));
            app.ExportButton.Enable = 'on';
            catch
                disp('error FilesListBoxClicked(app, event)')
            end
            try
                filename=[erase(filename,'.vsum'),'*.xlsx'];
                filename=dir(filename).name;
                if isfile(filename)
                   disp('associated .xlsx file found');
                   app.Button.Enable = 'on';
                   app.Panel_2.Enable = 'on';
                end
            catch ME
             disp(ME);                
             disp('.xlsx file associated not found - catch');
             app.Button.Enable = 'off';
             app.Panel_2.Enable = 'off';
            end
            
           
        end

        % Value changed function: NosummaryfilesCheckBox
        function NosummaryfilesCheckBoxValueChanged(app, event)
            value = app.NosummaryfilesCheckBox.Value;
            if value == 1
                app.PathEditField_2.Enable = 'on';
                app.PathEditField_2.Visible = 'on';
                app.OpenFilesButton_2.Enable ="on";
                app.OpenFilesButton_2.Visible ="on";
                app.Panel_2.Enable = 'on';

            else 
                app.PathEditField_2.Enable = 'off';
                app.PathEditField_2.Visible = 'off';  
                app.OpenFilesButton_2.Enable ="off";
                app.OpenFilesButton_2.Visible ="off";
                app.Panel_2.Enable = 'off';

            end
        end

        % Clicked callback: FilesListBox_2
        function FilesListBox_2Clicked(app, event)
            item = event.InteractionInformation.Item
            file = app.FilesListBox_2.Items{item}
            sheetname = matlab.lang.makeValidName(file(1:min(26,end)))
            columnSelection = [];
            data = VariableNamingRuletable(file,'', 'preserve','FileType','delimitedtext');
            app.VarsFoundListBox_2.Items=data.Properties.VariableNames;
            % timetable_data = table2timetable(data,"RowTimes",seconds(data.("time [s]")));
            % timetable_data = removevars(timetable_data,'time [s]')    
            % Obtener los nombres de las columnas
            columnNames = data.Properties.VariableNames;
            % Aplicar la selección de columnas y guardarlas en una tabla por cada archivo
            Files_data = data(:,item); %#ok<*SAGROW>            
                        
        end

        % Button pushed function: OpenFilesButton_2
        function OpenFilesButton_2Pushed(app, event)
            value = app.NosummaryfilesCheckBox.Value;
            if value ==1
                [vmod_files, folderPath] = uigetfile('*.vmod','Seleccionar archivo *.vmod','MultiSelect', 'on');
                if isequal(vmod_files, 0) % Ningún archivo seleccionado
                    disp('No se seleccionaron archivos.');
                else
                    disp(['Archivos seleccionados desde el directorio: ', folderPath]);
                    if ischar(vmod_files)
                        app.FilesListBox_2.Items = {vmod_files};
                    else
                        app.FilesListBox_2.Items = vmod_files;
                    end 
                        app.PathEditField_2.Value =folderPath;
                end


            else 
                [vmod_files, folderPath] = uigetfile('*.vmod','Seleccionar archivo *.vmod','MultiSelect', 'on');
                if isequal(vmod_files, 0) % Ningún archivo seleccionado
                    disp('No se seleccionaron archivos.');
                else
                    disp(['Archivos seleccionados desde el directorio: ', folderPath]);
                    if ischar(vmod_files)
                        app.FilesListBox_2.Items = {vmod_files};
                    else
                        app.FilesListBox_2.Items = vmod_files;
                    end 
                    app.PathEditField_2.Value =folderPath;
                end
            end

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.Panel_2 = uipanel(app.GridLayout);
            app.Panel_2.Enable = 'off';
            app.Panel_2.Title = ' ';
            app.GridLayout_2 = uigridlayout(app.Panel_2);
            app.GridLayout_2.Tooltip = {''};
            app.XaxisDropDownLabel = uilabel(app.GridLayout_2);
            app.XaxisDropDownLabel.Text = 'X -axis ';
            app.YaxisDropDownLabel = uilabel(app.GridLayout_2);
            app.YaxisDropDownLabel.Text = 'Y -axis ';
            app.XbinsEditFieldLabel = uilabel(app.GridLayout_2);
            app.XbinsEditFieldLabel.Enable = 'off';
            app.XbinsEditFieldLabel.Text = 'X bins';
            app.YbinsEditFieldLabel = uilabel(app.GridLayout_2);
            app.YbinsEditFieldLabel.Enable = 'off';
            app.YbinsEditFieldLabel.Text = 'Y  bins';
            app.FDREditFieldLabel = uilabel(app.GridLayout_2);
            app.FDREditFieldLabel.Enable = 'off';
            app.FDREditFieldLabel.Text = 'FDR';
            app.WheeloutputCheckBox = uicheckbox(app.GridLayout_2);
            app.WheeloutputCheckBox.Text = 'Wheel output';
            app.FDREditField = uieditfield(app.GridLayout_2, 'text');
            app.FDREditField.Enable = 'off';
            app.YbinsEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.YbinsEditField.Enable = 'off';
            app.AutobinsCheckBox = uicheckbox(app.GridLayout_2);
            app.AutobinsCheckBox.Text = 'Auto bins';
            app.AutobinsCheckBox.Value = true;
            app.XbinsEditField = uieditfield(app.GridLayout_2, 'numeric');
            app.XbinsEditField.Enable = 'off';
            app.GenerateButton = uibutton(app.GridLayout_2, 'push');
            app.GenerateButton.Enable = 'off';
            app.GenerateButton.Text = 'Generate';
            app.YaxisDropDown = uidropdown(app.GridLayout_2);
            app.XaxisDropDown = uidropdown(app.GridLayout_2);
            app.VarsFoundListBox_2 = uilistbox(app.GridLayout_2);
            app.VarsFoundListBox_2.Multiselect = 'on';
            app.VarsFoundListBox_2.Value = {'Item 1'};
            app.workspacevarsLabel = uilabel(app.GridLayout_2);
            app.workspacevarsLabel.Text = 'workspace vars ';
            app.Button_2 = uibutton(app.GridLayout_2, 'push');
            app.Button_2.Enable = 'off';
            app.Button_2.Text = '>>>';
            app.VarsFoundListBox = uilistbox(app.GridLayout_2);
            app.VarsFoundListBox.Multiselect = 'on';
            app.VarsFoundListBox.Value = {'Item 1'};
            app.VarsfoundLabel = uilabel(app.GridLayout_2);
            app.VarsfoundLabel.Text = 'Vars found';
            app.ExportButton_2 = uibutton(app.GridLayout_2, 'push');
            app.ExportButton_2.Enable = 'off';
            app.ExportButton_2.Text = 'Export';
            app.FilesListBox_2 = uilistbox(app.GridLayout_2);
            app.FilesListBox_2.Items = {'Files no found', ''};
            app.FilesListBox_2.Multiselect = 'on';
            app.FilesListBox_2.ClickedFcn = createCallbackFcn(app, @FilesListBox_2Clicked, true);
            app.FilesListBox_2.Value = {'Files no found'};
            app.OpenFilesButton_2 = uibutton(app.GridLayout_2, 'push');
            app.OpenFilesButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenFilesButton_2Pushed, true);
            app.OpenFilesButton_2.Visible = 'off';
            app.OpenFilesButton_2.Text = 'Open Files';
            app.PathEditField_2 = uieditfield(app.GridLayout_2, 'text');
            app.PathEditField_2.Editable = 'off';
            app.PathEditField_2.Enable = 'off';
            app.PathEditField_2.Visible = 'off';
            app.PathEditField_2.Value = '.csv';
            app.DutyCyclesGeneratorLabel = uilabel(app.GridLayout_2);
            app.DutyCyclesGeneratorLabel.Text = 'Duty Cycles Generator';
            app.Panel_1 = uipanel(app.GridLayout);
            app.Panel_1.Title = ' ';
            app.GridLayout_1 = uigridlayout(app.Panel_1);
            app.ExportButton = uibutton(app.GridLayout_1, 'push');
            app.ExportButton.ButtonPushedFcn = createCallbackFcn(app, @ExportButtonPushed, true);
            app.ExportButton.Enable = 'off';
            app.ExportButton.Text = 'Export ';
            app.UITable = uitable(app.GridLayout_1);
            app.UITable.ColumnName = {''; ''; ''; ''};
            app.UITable.RowName = {};
            app.FilesListBox = uilistbox(app.GridLayout_1);
            app.FilesListBox.Items = {'Files no found', ''};
            app.FilesListBox.Multiselect = 'on';
            app.FilesListBox.ClickedFcn = createCallbackFcn(app, @FilesListBoxClicked, true);
            app.FilesListBox.Value = {'Files no found'};
            app.OpenFilesButton = uibutton(app.GridLayout_1, 'push');
            app.OpenFilesButton.ButtonPushedFcn = createCallbackFcn(app, @OpenFilesButtonPushed, true);
            app.OpenFilesButton.Text = 'Open Files';
            app.PathEditField = uieditfield(app.GridLayout_1, 'text');
            app.PathEditField.Editable = 'off';
            app.PathEditField.Value = '.vsum';
            app.SummaryfilesgeneratorLabel = uilabel(app.GridLayout_1);
            app.SummaryfilesgeneratorLabel.Text = 'Summary files generator';
            app.Button = uibutton(app.GridLayout_1, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Enable = 'off';
            app.Button.Text = '>>>';
            app.CreateDutycyclesLabel = uilabel(app.GridLayout_1);
            app.CreateDutycyclesLabel.Text = {'Create '; 'Duty cycles'};
            app.NosummaryfilesCheckBox = uicheckbox(app.GridLayout_1);
            app.NosummaryfilesCheckBox.ValueChangedFcn = createCallbackFcn(app, @NosummaryfilesCheckBoxValueChanged, true);
            app.NosummaryfilesCheckBox.Text = {'No'; 'summary'; 'files'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = VECTO_summary_2_0

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end